classdef TakeHomeGui_2019 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SetupTab                      matlab.ui.container.Tab
        StartupInstructionsTextArea   matlab.ui.control.TextArea
        UIAxes_8                      matlab.ui.control.UIAxes
        MotorHomeTab                  matlab.ui.container.Tab
        TrainLongButton               matlab.ui.control.Button
        TrainShortButton              matlab.ui.control.Button
        ThumbFlexionExtensionLabel    matlab.ui.control.Label
        ThumbAdductionAbductionLabel  matlab.ui.control.Label
        WristRotationLabel            matlab.ui.control.Label
        IndexLabel                    matlab.ui.control.Label
        MiddleRingPinkyLabel          matlab.ui.control.Label
        WristFlexionExtensionLabel    matlab.ui.control.Label
        DropDownThumbFlex             matlab.ui.control.DropDown
        DropDownThumbAdd              matlab.ui.control.DropDown
        DropDownIndex                 matlab.ui.control.DropDown
        DropDownMRP                   matlab.ui.control.DropDown
        DropDownWristFlex             matlab.ui.control.DropDown
        DropDownWristRotation         matlab.ui.control.DropDown
        SaveCurrentControlSettingsButton  matlab.ui.control.Button
        SetAllDropDownLabel           matlab.ui.control.Label
        SetAllDropDown                matlab.ui.control.DropDown
        LoadControlSettingsButton     matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        SensoryHomeTab                matlab.ui.container.Tab
        UIAxes_7                      matlab.ui.control.UIAxes
        StimRegion2SecondsButton      matlab.ui.control.Button
        DirectionsTextArea            matlab.ui.control.TextArea
        RegionDropDownLabel           matlab.ui.control.Label
        RegionDropDown                matlab.ui.control.DropDown
        DecreaseStimButton            matlab.ui.control.Button
        IncreaseStimButton            matlab.ui.control.Button
        OnButton                      matlab.ui.control.StateButton
        LampLabel                     matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        MinAmpGaugeLabel              matlab.ui.control.Label
        MinAmpGauge                   matlab.ui.control.LinearGauge
        STOPSTIMButton                matlab.ui.control.Button
        STOPHANDButton                matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        CmdTags = {'MotorOn ';'MotorOff';'MotorPar';'StimOn  ';'StimOff ';'StimPar ';'Retrain ';'ShutDown';'LoadDeco';'StmTrain';'SendKTFs';'calbDEKA';'SendWTSs'};
        minAmp = [NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN] %% loaded externally Only 13. One for each sensor
        stimOnOff = [NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN,NaN] %% loaded externally
        DEKAMapping = [1,1,2,3,4,5,6,7,8,8,8,9,10,11,12]' %% Hardcoded by experimenters Sensors Mapped to Elecs below
        Elecs = [1,2,3,8,10,12,14,16,18,20,22,24,26,28,30]' %% Hardcoded by experimenters elecs mapped to sensors above
        hardCodedAmpMin = [10,10,10,10,10,10,10,10,10,10,10,10,10,10,10,10] %% THIS SHOULD NEVER BE CHANGED BY ANYONE BUT EXPERIMENTERS
    end
    
    
    methods (Access = private)
        
        function [CtrlMode_GUI, fixedDOF_GUI] = motorSpecify(app,modes)
            for i = 1:6
                if modes(i) == "Lock"
                    fixedDOF_GUI(i) = 1; %% default is position
                    CtrlMode_GUI(i) = 1;
                elseif modes(i) == "Position"
                    CtrlMode_GUI(i) = 1;
                    fixedDOF_GUI(i) = 0;
                else
                    CtrlMode_GUI(i) = 2;
                    fixedDOF_GUI(i) = 0;
                end
            end
            fixedDOF_GUI(7) = 1;
            CtrlMode_GUI(7) = 1;
        end
        
        function UpdateMotorParams(app,CmdStr,CtrlMode_GUI,fixedDOF_GUI  )
            %% Motor Params (i.e. DPF data)
            
            %% Fixed for now (could be included in GUI in future)
            maxDOFs = 7;   %%% GUI always sends fields the size of the maxDOFs possible with LUKE (6+bias = 7)
            
            % Kalman Gain is fixed at 1 for now
            KalmanGain = ones(maxDOFs,2);
            
            % Kalman Threshold is fixed at 0.2 for now
            Th = 0.2;
            KalmanThresh = ones(maxDOFs,2)*Th;
            
            % CtrlSpeed is fixed at 1 (digits) and 0.95 (wrists) for now
            CtrlSpeed = ones(maxDOFs,1);
            CtrlSpeed(5:6) = 0.95;
            
            %CtrlModeVel is always fixed: this describes the state of the control
            %on the DEKA PI interface (not adjustable via GUI)
            CtrlModeVel = ones(maxDOFs,1)*2;
            CtrlModeVel(5:6,1) = 1;
            
            % allVel is obsolete so include but ignore for now
            allVel = 0;
            
            % RestPos is not adjustable via the GUI (though it could be in the
            % future). These are a good default.
            restPos = zeros(maxDOFs,1);
            restPos(4) = 0.75;
            restPos(1) = -0.6;
            
            % LF_C is currently fixed at 1 but could be made adjustable in future
            % GUIs if useful.
            LF_C = 1;
            % LinkedDOF is not active currently, so include but ignore.
            LinkedDOF = [];
            % RH: if 1 then true, if 0 then it is actually the LH (this
            % functionally doens't matter, but included in case it might in
            % future).
            RH = 1;
            
            %% To Be Updated with GUI
            % CtrlMode WILL BE UPDATED via the GUI: CtrlMode of 1 = position, 2 =
            % velocity control. This describes how you actually want the arm to
            % behave.
            CtrlMode = ones(maxDOFs,1);
            if isempty(CtrlMode_GUI) == 0
                CtrlMode = CtrlMode_GUI;
            end
            
            %% To Be Updated with GUI
            % Fixed DOFs WILL BE UPDATED with the GUI. This allows the user to fix a
            % specific DOF (fixedDOFs) at a specific location (FixPosition: -1 to 1)
            fixedDOFs = zeros(maxDOFs,1);
            if isempty(fixedDOF_GUI) == 0
                fixedDOFs = fixedDOF_GUI;
            end
            
            
            FixPosition = zeros(maxDOFs,1);
            FixPosition(2,1) = -1;
            FixPosition(6,1) = 0.5;
            
            %% This code will send these parameters to the Nomad so that they can be
            % read in and implemented in real-time.
            % This Code MUST BE IN THIS FORM, otherwise the Nomad won't reconstruct the
            % transmitted data correctly
            Dsize = [...
                size(KalmanGain,1);size(KalmanGain,2);...
                size(KalmanThresh,1); size(KalmanThresh,2);...
                size(CtrlSpeed,1); size(CtrlSpeed,2);...
                size(CtrlMode,1); size(CtrlMode,2);...
                size(CtrlModeVel,1); size(CtrlModeVel,2);...
                size(allVel,1); size(allVel,2);...
                size(restPos,1); size(restPos,2);...
                size(fixedDOFs,1); size(fixedDOFs,2);...
                size(FixPosition,1); size(FixPosition,2);...
                size(LF_C,1); size(LF_C,2);...
                size(LinkedDOF,1); size(LinkedDOF,2);...
                size(RH,1); size(RH,2);...
                ]; %writing data array size
            
            Dlength = [...
                1;...
                numel(KalmanGain);...
                numel(KalmanThresh);...
                numel(CtrlSpeed);...
                numel(CtrlMode);...
                numel(CtrlModeVel);...
                numel(allVel);...
                numel(restPos);...
                numel(fixedDOFs);...
                numel(FixPosition);...
                numel(LF_C);...
                numel(LinkedDOF);...
                numel(RH);...
                ];
            
            Data = [...
                reshape(KalmanGain,[numel(KalmanGain),1]);...
                reshape(KalmanThresh,[numel(KalmanThresh),1]);...
                reshape(CtrlSpeed,[numel(CtrlSpeed),1]);...
                reshape(CtrlMode,[numel(CtrlMode),1]);...
                reshape(CtrlModeVel,[numel(CtrlModeVel),1]);...
                reshape(allVel,[numel(allVel),1]);...
                reshape(restPos,[numel(restPos),1]);...
                reshape(fixedDOFs,[numel(fixedDOFs),1]);...
                reshape(FixPosition,[numel(FixPosition),1]);...
                reshape(LF_C,[numel(LF_C),1]);...
                reshape(LinkedDOF,[numel(LinkedDOF),1]);...
                reshape(RH,[numel(RH),1])...
                ];
            
            yEnc = ms_send_array(CmdStr,Dlength'); % send info about length of each field
            yEnc = ms_send_array(CmdStr,Dsize'); % send info about the 2D size of each field
            yEnc = ms_send_array(CmdStr,Data'); % send data
        end
        
        
        function UpdateStimParams(app, AmpMin_GUI,CmdStr, DEKA_ONOFF_GUI)
            %% Send Stimulation Params (i.e. EPF data)
            %% Fixed parameters (but could be update in future GUIs)
            % Elecs and DEKAMapping are fixed as far as the GUI is concerned
            % - They will be updated by lab members after lab visits...at some point I
            % will write a function to read in a specific file to get these data. But
            % just keep fixed for now.
            % Elecs: %%% a list of electrodes assigned to the mapping of each
            % DEKA sensor (1-13 contact + 14-19 Proprioception)
            % SS.DEKASensorLabels = {'index_medial';'index_distal';'middle_distal';'ring_distal';...
            %     'pinky_distal';'palm_pinky';'palm_thumb';'palm_side';'palm_back';...
            %     'thumb_ulnar';'thumb_medial';'thumb_distal';'thumbdorsal'};
            Elecs = app.Elecs;
            %DEKAMapping: the deka sensor # tied to each electrode in Elecs (e.g. Sensor 1
            %is tied to both electrode 1 and 2;
            %and 11)
            DEKAMapping = app.DEKAMapping;
            % The Encoding Algorithm to use: fixed for now
            Alg = 4;
            EncodeAlg = ones(size(Elecs))*Alg;
            % SensorFudge is fixed at these values for now...but we may include it in future revisions
            SensorFudge = zeros(13,1);
            SensorFudge(6) = 0.1; % FOR RH 3/31/2020
            SensorFudge(9) = 0.5; % FOR RH 3/31/2020
            SensorFudge(7:8) = 0.2; % FOR RH 3/31/2020
            % FreqMin and FreqMax and PWDur will be fixed for now (e.g. not GUI adjustable).
            FMin = 1; % Hz
            FMax = 200; % Hz
            PW = 0.1; % ms
            FreqMin = ones(size(Elecs))*FMin;
            FreqMax = ones(size(Elecs))*FMax;
            PWDur = ones(size(Elecs))*PW;
            
            %% These field will be updated via the GUI
            % Amin and Amax WILL BE UPDATED for each sensor (all electrodes tied to
            % that sensor will have the same Amin and Amax) using the GUI interface
            AMin = 1; % uA
            AMax = 100; % uA
            AmpMin = app.minAmp %ones(size(Elecs))*AMin; % They don't all need to be the same...GUI should actually train each sensor (and assoc. electrodes).
            if(isempty(AmpMin_GUI) == 0) %%If this array isn't empty, set it to the proper values.
                AmpMin = AmpMin_GUI + app.hardCodedAmpMin; %Set values to values given by the GUI
            end
            
            AmpMax = ones(size(Elecs))*AMax; % Also, different for each sensor based on training
            
            % DEKA_ONOFF WILL BE UPDATED for each sensor using the GUI
            % When the GUI deactivates a specific sensor the corresponding index in
            % this array becomes a 1.
            % Sensor Order: index_medial, index_tip, middle, ring, pinky, palm_distal,
            % palm_proximal, hand_side, hand_back, thumb_ulnar (e.g. force applied that
            % pushes thumb toward ulnar side of hand), thumb_radial (e.g. opp. ulnar),
            % thumb_tip, thumb_dorsal (e.g. force applied to thumb nail)
            
            
            DEKA_ONOFF = app.stimOnOff %zeros(length(DEKAMapping),1); %%% which stim (DEKA sensors) are on (0) and which are off (1)
            if(isempty(DEKA_ONOFF_GUI) == 0) %%If this array isn't empty, set it to the proper values.
                DEKA_ONOFF = DEKA_ONOFF_GUI; %Set values to values given by the GUI
            end
            % DEKA_ONOFF([1,8]) =1; %turn off by setting to 1
                
            % StimOn WILL BE UPDATED with GUI. If StimOn = 1 stim is on, if 0 its off
            % This turns global stim on and off (whereas DEKA_ONOFF toggles a specific
            % sensor).
            StimOn = 1;
            
            %% This code will send these parameters to the Nomad so that they can be
            % read in and implemented in real-time.
            % This Code MUST BE IN THIS FORM, otherwise the Nomad won't reconstruct the
            % data correctly
            Dlength = [1;...
                size(Elecs,1);...
                size(DEKAMapping,1);...
                size(AmpMin,1);...
                size(AmpMax,1);...
                size(FreqMin,1);...
                size(FreqMax,1);...
                size(PWDur,1);...
                size(DEKA_ONOFF,1);...
                size(EncodeAlg,1);...
                size(StimOn,1);...
                size(SensorFudge,1);...
                ];
            Data = [...
                reshape(Elecs,[numel(Elecs),1]);...
                reshape(DEKAMapping,[numel(DEKAMapping),1]);...
                reshape(AmpMin,[numel(AmpMin),1]);...
                reshape(AmpMax,[numel(AmpMax),1]);...
                reshape(FreqMin,[numel(FreqMin),1]);...
                reshape(FreqMax,[numel(FreqMax),1]);...
                reshape(PWDur,[numel(PWDur),1]);...
                reshape(DEKA_ONOFF,[numel(DEKA_ONOFF),1]);...
                reshape(EncodeAlg,[numel(EncodeAlg),1]);...
                reshape(StimOn,[numel(StimOn),1]);...
                reshape(SensorFudge,[numel(SensorFudge),1]);...
                ];
            yDec = ms_send_array(CmdStr,Dlength'); %send info about the length of each data field
            yDec = ms_send_array(CmdStr,Data'); %send data
            
            minAmp = AmpMin - app.hardCodedAmpMin; %% Data to be saved
            stimOnOff = DEKA_ONOFF; %% Data to be saved
            save('stimVariables','minAmp','stimOnOff');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function imageShow(app)
            cd 'C:\Users\Administrator\Code\Analysis\TNT\Take Home Gui'
            %             imshow('Cartoon_Hands.jpg', 'Parent', app.UIAxes);
            %             imshow('Cartoon_Hands.jpg', 'Parent', app.UIAxes_2);
            %             imshow('Cartoon_Hands.jpg', 'Parent', app.UIAxes_3);
            %             imshow('Cartoon_Hands.jpg', 'Parent', app.UIAxes_4);
            %             imshow('Cartoon_Hands.jpg', 'Parent', app.UIAxes_4);
            %             imshow('Cartoon_Hands.jpg', 'Parent', app.UIAxes_5);
            %             imshow('Cartoon_Hands.jpg', 'Parent', app.UIAxes_6);
            imshow('LeftHand_sensor_colored.jpg', 'Parent', app.UIAxes_7);
            imshow('Nomad.jpg', 'Parent', app.UIAxes_8);
            
            try
                ms_open()
                xl_open_tcp();
            catch ME
                
            end
        end

        % Value changed function: DropDownIndex, DropDownMRP, 
        % DropDownThumbAdd, DropDownThumbFlex, DropDownWristFlex, 
        % DropDownWristRotation
        function MotorControlChange(app, event)
            
            value(1) = cellstr([app.DropDownThumbFlex.Value]);
            value(3) = cellstr([app.DropDownThumbAdd.Value]);
            value(2) = cellstr([app.DropDownIndex.Value]);
            value(4) = cellstr([app.DropDownMRP.Value]);
            value(5) = cellstr([app.DropDownWristFlex.Value]);
            value(6) = cellstr([app.DropDownWristRotation.Value]);
            value(7) = cellstr([app.DropDownWristRotation.Value]);;
            
            [ctrl , fixedDof] = motorSpecify(app,value);
            UpdateMotorParams(app,app.CmdTags{3}, ctrl , fixedDof);
            
            motorDropdowns = value;  %% update recently used motor params
            save('motorVariables','motorDropdowns'); %% update recently used motor params
            
            y = ms_recv_array() % send data
            disp(y)
        end

        % Callback function
        function SaveButtomPushed(app, event)
            value = cellstr([app.DropDownThumbFlex.Value]);
            value(2) = cellstr([app.DropDownThumbAdd.Value]);
            value(3) = cellstr([app.DropDownIndex.Value]);
            value(4) = cellstr([app.DropDownMRP.Value]);
            value(5) = cellstr([app.DropDownWristFlex.Value]);
            value(6) = cellstr([app.DropDownWristRotation.Value]);
            
            SavePath =  inputdlg("Enter name of Settings to be saved as","Filename");
            
            dt = datestr(now,'dd-mmm-yyyy HH:MM:SS');
            dt = regexprep(dt, ' ', '_');
            dt = regexprep(dt, ':', '-');
            MATFile = [savepath  dt '_' SavePath '_' note '_Motor.mat'];
            save(MATFile,'value'); %saves relevant variables for
            
        end

        % Button pushed function: TrainShortButton
        function TrainShortButtonPushed(app, event)
            %ms_send_array(app.CmdTags{1},1);
            ms_send_array(app.CmdTags{7},1);
            WTSfile = 'IndexFE_6Trials.WTS'; %%% this could be selected from among a list of files on the GUI
            %             WTSfile = 'DEKA_4trials_5DOF_nocombo.WTS';
            ms_send_array(WTSfile,1); %% WTSfile is the training file to use:
            
        end

        % Button pushed function: TrainLongButton
        function TrainLongButtonPushed(app, event)
            ms_send_array(app.CmdTags{7},1);
            %            WTSfile = 'IndexFE_6Trials.WTS'; %%% this could be selected from among a list of files on the GUI
            WTSfile = 'DEKA_4trials_5DOF_GrspWrRt.WTS'
            ms_send_array(WTSfile,1); %% WTSfile is the training file to use:
            
        end

        % Callback function
        function StartCommunicationButtonPushed2(app, event)
            %% This must be run during the setup phase to connect the GUI device with
            % the Nomad each time Wifi is connected/reconnected
            ms_open()
            xl_open_tcp();
        end

        % Button pushed function: STOPSTIMButton
        function STOPSTIMButtonPushed(app, event)
            ms_send_array(app.CmdTags{5},1);
        end

        % Button pushed function: STOPHANDButton
        function STOPHANDButtonPushed(app, event)
            ms_send_array(app.CmdTags{2},1);
        end

        % Value changed function: SetAllDropDown
        function SetAllDropDownValueChanged(app, event)
            value(1:7) = cellstr([app.SetAllDropDown.Value]);
            
            [ctrl , fixedDof] = motorSpecify(app,value);
            UpdateMotorParams(app,app.CmdTags{3}, ctrl , fixedDof);
            
            app.DropDownThumbFlex.Value= value(1);
            app.DropDownThumbAdd.Value = value(2);
            app.DropDownIndex.Value = value(3);
            app.DropDownMRP.Value = value(4);
            app.DropDownWristFlex.Value = value(5);
            app.DropDownWristRotation.Value= value(6);
            
            motorDropdowns = value;  %% update recently used motor params
            save('motorVariables','motorDropdowns'); %% update recently used motor params
            y = ms_recv_array() % send data
        end

        % Button pushed function: LoadControlSettingsButton
        function LoadControlSettingsButtonPushed(app, event)
            
            load(uigetfile(pwd,"Load Control Settings File (.mat)") );
            
            [ctrl , fixedDof] = motorSpecify(app,value);
            UpdateMotorParams(app,app.CmdTags{3}, ctrl , fixedDof);
            
            app.DropDownThumbFlex.Value= value(1);
            app.DropDownThumbAdd.Value = value(2);
            app.DropDownIndex.Value = value(3);
            app.DropDownMRP.Value = value(4);
            app.DropDownWristFlex.Value = value(5);
            app.DropDownWristRotation.Value= value(6);
            
            motorDropdowns = value;  %% update recently used motor params
            save('motorVariables','motorDropdowns'); %% update recently used motor params
            y = ms_recv_array() % send data
            
        end

        % Button pushed function: SaveCurrentControlSettingsButton
        function SaveCurrentControlSettingsButtonPushed(app, event)
            value = cellstr([app.DropDownThumbFlex.Value]);
            value(2) = cellstr([app.DropDownThumbAdd.Value]);
            value(3) = cellstr([app.DropDownIndex.Value]);
            value(4) = cellstr([app.DropDownMRP.Value]);
            value(5) = cellstr([app.DropDownWristFlex.Value]);
            value(6) = cellstr([app.DropDownWristRotation.Value]);
            
            savePath =  inputdlg("Enter name of Settings to be saved as","Filename");
            disp(savePath)
            
            dt = datestr(now,'dd-mmm-yyyy HH:MM:SS');
            dt = regexprep(dt, ' ', '_');
            dt = regexprep(dt, ':', '-');
            
            save(string(savePath)+  '_Motor.mat','value'); % saves a specific file that is named by user for later selection
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            dekaRegion = str2num(app.RegionDropDown.Value)
            app.OnButton.Text
            %% update stim parameters
            if(app.stimOnOff(dekaRegion) == 1)
                app.OnButton.Text = 'Off'
                app.Lamp.Color = 'red'; %% region is off
                app.OnButton.Value = 0;
            else
                app.OnButton.Text = 'On'
                app.Lamp.Color = 'green'; %% region is on
                app.OnButton.Value = 0;
            end
            app.MinAmpGauge.Value = app.minAmp(dekaRegion) ;   %% Find current minAmp


        end

        % Button pushed function: StimRegion2SecondsButton
        function StimRegion2SecondsButtonPushed(app, event)
            dekaRegion = str2num(app.RegionDropDown.Value)
            
            SendElecs = app.Elecs(dekaRegion == app.DEKAMapping); % can be more than 1 electrode
            numElecs = numel(SendElecs); % always 1x1
            
            A = ones(numElecs,1)*10; %% Units depends on if its Electrocutaneous(mA) or Implanted(uA)...
            F = ones(numElecs,1)*100; % Hz
            PulseW = ones(numElecs,1)*0.1; %ms
            StimDur = 2; %seconds - always 1x1
            %% DekaRegion = 1; % any one of the DEKA sensors: Stim will occur on ALL the electrodes tied to this sensor in the EPF file
            %%% Use these two lines of code to send a new stim for the
            %%% person to feel
            y = ms_send_array(app.CmdTags{10},1);
            y = ms_send_array('NewStTrn',[dekaRegion;numElecs;SendElecs;A;F;PulseW;StimDur]'); %%% must be an 1 x ? data array
            y = ms_recv_array() % receive data
            %%% Send this line of code when you are finished setting the stim thresholds
            y = ms_send_array('StpStTrn',1);
        end

        % Value changed function: OnButton
        function OnOffButtonValueChanged(app, event)
            dekaRegion = str2num(app.RegionDropDown.Value)
            value = app.stimOnOff(dekaRegion)
            disp('turning sensor off!')
            if( value == 1) %%if its off, turn it on
                app.Lamp.Color = 'green'; %% region is on
                app.OnButton.Text = 'On'
                app.stimOnOff(dekaRegion) = 0; % Turn on region
                app.OnButton.Value = 0;
                
            else
                app.Lamp.Color = 'red'; %% region is off
                app.OnButton.Text = 'Off'
                app.stimOnOff(dekaRegion) = 1; % Turn off region
                app.OnButton.Value = 0;
            end
            
            UpdateStimParams(app, app.minAmp,app.CmdTags{6}, app.stimOnOff)
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            cd 'C:\Users\Administrator\Code\Analysis\TNT\Take Home Gui'
            load([pwd,'/motorVariables.mat']) %% load most recently used variables
            value = motorDropdowns;
            
            app.DropDownThumbFlex.Value= value(1);
            app.DropDownThumbAdd.Value = value(2);
            app.DropDownIndex.Value = value(3);
            app.DropDownMRP.Value = value(4);
            app.DropDownWristFlex.Value = value(5);
            app.DropDownWristRotation.Value= value(6);
            
            [ctrl , fixedDof] = motorSpecify(app,value);
            UpdateMotorParams(app,app.CmdTags{3}, ctrl , fixedDof); %Make sure the Nomad and the Saved Gui params are the same
            cd 'C:\Users\Administrator\Code\Analysis\TNT\Take Home Gui'
            load([pwd,'/stimVariables.mat']) %% load most recently used variables
            app.minAmp =   minAmp
            app.stimOnOff = stimOnOff
            
            
            dekaRegion = 1 %By default use the first sensor
            value = app.stimOnOff(dekaRegion)
            disp('turning sensor off!')
            if( value == 0)
                app.Lamp.Color = 'green'; %% region is on
                app.OnButton.Text = 'On'
            else
                app.Lamp.Color = 'red'; %% region is off
                app.OnButton.Text = 'Off'
            end
            app.MinAmpGauge.Value = app.minAmp(dekaRegion) ;
            
            try
                ms_open()
                xl_open_tcp();
            catch ME
                
            end
        end

        % Button pushed function: DecreaseStimButton
        function DecreaseStimButtonPushed(app, event)
            dekaRegion = str2num(app.RegionDropDown.Value)
            if (app.minAmp(dekaRegion) >= -45)
                app.minAmp(dekaRegion) = app.minAmp(dekaRegion) - 5;   %% Find current minAmp
                app.MinAmpGauge.Value = app.minAmp(dekaRegion) ;   %% Find current minAmp and display on gauge
                
                UpdateStimParams(app, app.minAmp,app.CmdTags{6}, app.stimOnOff)
                
                
                SendElecs = app.Elecs(dekaRegion == app.DEKAMapping); % can be more than 1 electrode
                numElecs = numel(SendElecs); % always 1x1
                
                A = app.hardCodedAmpMin(dekaRegion == app.DEKAMapping) + app.minAmp(dekaRegion) % ones(numElecs,1)*10; %% Units depends on if its Electrocutaneous(mA) or Implanted(uA)...
                F = ones(numElecs,1)*100; % Hz
                PulseW = ones(numElecs,1)*0.1; %ms
                StimDur = 2; %seconds - always 1x1
                %% DekaRegion = 1; % any one of the DEKA sensors: Stim will occur on ALL the electrodes tied to this sensor in the EPF file
                %%% Use these two lines of code to send a new stim for the
                %%% person to feel
                y = ms_send_array(app.CmdTags{10},1);
                y = ms_send_array('NewStTrn',[dekaRegion;numElecs;SendElecs;A;F;PulseW;StimDur]'); %%% must be an 1 x ? data array
                %%% Send this line of code when you are finished setting the stim thresholds
                y = ms_send_array('StpStTrn',1);
                
                y = ms_recv_array() % receive data
            else
                %%DISPLAY ERROR FOR USER
            end
            
        end

        % Button pushed function: IncreaseStimButton
        function IncreaseStimButtonPushed(app, event)
            dekaRegion = str2num(app.RegionDropDown.Value)
            if (app.minAmp(dekaRegion) <= 45)
                app.minAmp(dekaRegion) = app.minAmp(dekaRegion) + 5;
                app.MinAmpGauge.Value = app.minAmp(dekaRegion) ;  %% Find current minAmp and display on gauge
                
                UpdateStimParams(app, app.minAmp,app.CmdTags{6}, app.stimOnOff)
                SendElecs = app.Elecs(dekaRegion == app.DEKAMapping); % can be more than 1 electrode
                numElecs = numel(SendElecs); % always 1x1
                
                %                 A = app.hardCodedAmpMin(dekaRegion == app.DEKAMapping) + app.minAmp(dekaRegion) % ones(numElecs,1)*10; %% Units depends on if its Electrocutaneous(mA) or Implanted(uA)...
                A = ones(numElecs,1)*10
                F = ones(numElecs,1)*100; % Hz
                PulseW = ones(numElecs,1)*0.1; %ms
                StimDur = 2; %seconds - always 1x1
                %% DekaRegion = 1; % any one of the DEKA sensors: Stim will occur on ALL the electrodes tied to this sensor in the EPF file
                %%% Use these two lines of code to send a new stim for the
                %%% person to feel
                y = ms_send_array(app.CmdTags{10},1);
                y = ms_send_array('NewStTrn',[dekaRegion;numElecs;SendElecs;A;F;PulseW;StimDur]'); %%% must be an 1 x ? data array
                %%% Send this line of code when you are finished setting the stim thresholds
                y = ms_send_array('StpStTrn',1);
                y = ms_recv_array() % receive data
            else
                %DISPLAY ERROR FOR USER
            end
            
        end

        % Callback function
        function StopCommunicationButtonPushed(app, event)
            ms_close();
            xl_close();
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            ms_close();
            xl_close();
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.StartupInstructionsTextArea = uitextarea(app.SetupTab);
            app.StartupInstructionsTextArea.Value = {'1. After donning the arm, battery and Nomad, double check that the neural arrays are plugged into ports A and B (if applicable), and the muscle array is connected to port C (Labeled EMG) and all three lights are green.'; ''; '2. Press the power button on the Nomad and wait until the light turns green.'; ''; '3. Press the F2 button to start up the application and Wifi on the Nomad. '; ''; '4. Wait ~10 seconds till wifi turns on and make sure wifi is connected to Laptop'; ''; '5.Start using Laptop application to change settings.'};
            app.UIAxes_8 = uiaxes(app.SetupTab);
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.MotorHomeTab = uitab(app.TabGroup);
            app.MotorHomeTab.Title = 'Motor Home';
            app.TrainLongButton = uibutton(app.MotorHomeTab, 'push');
            app.TrainLongButton.ButtonPushedFcn = createCallbackFcn(app, @TrainLongButtonPushed, true);
            app.TrainLongButton.Text = 'Train Long';
            app.TrainShortButton = uibutton(app.MotorHomeTab, 'push');
            app.TrainShortButton.ButtonPushedFcn = createCallbackFcn(app, @TrainShortButtonPushed, true);
            app.TrainShortButton.Text = 'Train Short';
            app.ThumbFlexionExtensionLabel = uilabel(app.MotorHomeTab);
            app.ThumbFlexionExtensionLabel.Text = 'Thumb Flexion/Extension';
            app.ThumbAdductionAbductionLabel = uilabel(app.MotorHomeTab);
            app.ThumbAdductionAbductionLabel.Text = 'Thumb Adduction/Abduction';
            app.WristRotationLabel = uilabel(app.MotorHomeTab);
            app.WristRotationLabel.Text = 'Wrist Rotation';
            app.IndexLabel = uilabel(app.MotorHomeTab);
            app.IndexLabel.Text = 'Index';
            app.MiddleRingPinkyLabel = uilabel(app.MotorHomeTab);
            app.MiddleRingPinkyLabel.Text = 'Middle-Ring-Pinky';
            app.WristFlexionExtensionLabel = uilabel(app.MotorHomeTab);
            app.WristFlexionExtensionLabel.Text = 'Wrist Flexion/Extension';
            app.DropDownThumbFlex = uidropdown(app.MotorHomeTab);
            app.DropDownThumbFlex.Items = {'Lock', 'Position', 'Velocity'};
            app.DropDownThumbFlex.ValueChangedFcn = createCallbackFcn(app, @MotorControlChange, true);
            app.DropDownThumbFlex.Value = 'Lock';
            app.DropDownThumbAdd = uidropdown(app.MotorHomeTab);
            app.DropDownThumbAdd.Items = {'Lock', 'Position', 'Velocity'};
            app.DropDownThumbAdd.ValueChangedFcn = createCallbackFcn(app, @MotorControlChange, true);
            app.DropDownThumbAdd.Value = 'Lock';
            app.DropDownIndex = uidropdown(app.MotorHomeTab);
            app.DropDownIndex.Items = {'Lock', 'Position', 'Velocity'};
            app.DropDownIndex.ValueChangedFcn = createCallbackFcn(app, @MotorControlChange, true);
            app.DropDownIndex.Value = 'Lock';
            app.DropDownMRP = uidropdown(app.MotorHomeTab);
            app.DropDownMRP.Items = {'Lock', 'Position', 'Velocity'};
            app.DropDownMRP.ValueChangedFcn = createCallbackFcn(app, @MotorControlChange, true);
            app.DropDownMRP.Value = 'Lock';
            app.DropDownWristFlex = uidropdown(app.MotorHomeTab);
            app.DropDownWristFlex.Items = {'Lock', 'Position', 'Velocity'};
            app.DropDownWristFlex.ValueChangedFcn = createCallbackFcn(app, @MotorControlChange, true);
            app.DropDownWristFlex.Value = 'Lock';
            app.DropDownWristRotation = uidropdown(app.MotorHomeTab);
            app.DropDownWristRotation.Items = {'Lock', 'Position', 'Velocity'};
            app.DropDownWristRotation.ValueChangedFcn = createCallbackFcn(app, @MotorControlChange, true);
            app.DropDownWristRotation.Value = 'Lock';
            app.SaveCurrentControlSettingsButton = uibutton(app.MotorHomeTab, 'push');
            app.SaveCurrentControlSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentControlSettingsButtonPushed, true);
            app.SaveCurrentControlSettingsButton.Text = 'Save Current Control Settings';
            app.SetAllDropDownLabel = uilabel(app.MotorHomeTab);
            app.SetAllDropDownLabel.Text = 'Set All';
            app.SetAllDropDown = uidropdown(app.MotorHomeTab);
            app.SetAllDropDown.Items = {'None', 'Lock', 'Position', 'Velocity'};
            app.SetAllDropDown.ValueChangedFcn = createCallbackFcn(app, @SetAllDropDownValueChanged, true);
            app.SetAllDropDown.Value = 'None';
            app.LoadControlSettingsButton = uibutton(app.MotorHomeTab, 'push');
            app.LoadControlSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadControlSettingsButtonPushed, true);
            app.LoadControlSettingsButton.Text = 'Load Control Settings';
            app.TextArea = uitextarea(app.MotorHomeTab);
            app.TextArea.Value = {'Set Motor Parameters'};
            app.SensoryHomeTab = uitab(app.TabGroup);
            app.SensoryHomeTab.Title = 'Sensory Home';
            app.UIAxes_7 = uiaxes(app.SensoryHomeTab);
            title(app.UIAxes_7, 'Stim Areas')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.StimRegion2SecondsButton = uibutton(app.SensoryHomeTab, 'push');
            app.StimRegion2SecondsButton.ButtonPushedFcn = createCallbackFcn(app, @StimRegion2SecondsButtonPushed, true);
            app.StimRegion2SecondsButton.Text = 'Stim Region (2 Seconds) ';
            app.DirectionsTextArea = uitextarea(app.SensoryHomeTab);
            app.DirectionsTextArea.Value = {'Select the correct region tied to the appropriate sensor below'; ''};
            app.RegionDropDownLabel = uilabel(app.SensoryHomeTab);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.SensoryHomeTab);
            app.RegionDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.RegionDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = '1';
            app.DecreaseStimButton = uibutton(app.SensoryHomeTab, 'push');
            app.DecreaseStimButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseStimButtonPushed, true);
            app.DecreaseStimButton.Text = '-';
            app.IncreaseStimButton = uibutton(app.SensoryHomeTab, 'push');
            app.IncreaseStimButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseStimButtonPushed, true);
            app.IncreaseStimButton.Text = '+';
            app.OnButton = uibutton(app.SensoryHomeTab, 'state');
            app.OnButton.ValueChangedFcn = createCallbackFcn(app, @OnOffButtonValueChanged, true);
            app.OnButton.Text = 'On';
            app.LampLabel = uilabel(app.SensoryHomeTab);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.SensoryHomeTab);
            app.MinAmpGaugeLabel = uilabel(app.SensoryHomeTab);
            app.MinAmpGaugeLabel.Text = 'Min Amp';
            app.MinAmpGauge = uigauge(app.SensoryHomeTab, 'linear');
            app.MinAmpGauge.Limits = [-50 50];
            app.MinAmpGauge.MajorTicks = [-50 -30 -10 0 10 30 50];
            app.STOPSTIMButton = uibutton(app.UIFigure, 'push');
            app.STOPSTIMButton.ButtonPushedFcn = createCallbackFcn(app, @STOPSTIMButtonPushed, true);
            app.STOPSTIMButton.Text = 'STOP STIM';
            app.STOPHANDButton = uibutton(app.UIFigure, 'push');
            app.STOPHANDButton.ButtonPushedFcn = createCallbackFcn(app, @STOPHANDButtonPushed, true);
            app.STOPHANDButton.Text = 'STOP HAND';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TakeHomeGui_2019

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @imageShow)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end