classdef stimUpdate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        StimRegion2sButton  matlab.ui.control.Button
        Button              matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        SaveandExitButton   matlab.ui.control.Button
        GaugeLabel          matlab.ui.control.Label
        Gauge               matlab.ui.control.Gauge
    end

    
    methods (Access = private)
        
function UpdateStimParams(app,CmdStr, DEKA_ONOFF_GUI)
        %% Send Stimulation Params (i.e. EPF data)
        %% Fixed parameters (but could be update in future GUIs)
        % Elecs and DEKAMapping are fixed as far as the GUI is concerned
        % - They will be updated by lab members after lab visits...at some point I
        % will write a function to read in a specific file to get these data. But
        % just keep fixed for now.
        % Elecs: %%% a list of electrodes assigned to the mapping of each
        % DEKA sensor (1-13 contact + 14-19 Proprioception)
        Elecs = [2,4,6,8,10,12,14,16,18,20,22,24,26,28,30]'; 
        %DEKAMapping: the deka sensor # tied to each electrode in Elecs (e.g. Sensor 1
        %is tied to both electrode 2 and 4; Sensor 8 to electrodes 9, 10
        %and 11)
        DEKAMapping = [1,1,2,3,4,5,6,7,8,8,8,9,10,11,12]'; 
        % The Encoding Algorithm to use: fixed for now
        Alg = 4;
        EncodeAlg = ones(size(Elecs))*Alg;
        % SensorFudge is fixed at these values for now...but we may include it in future revisions
        SensorFudge = zeros(13,1);
        SensorFudge(6) = 0.1; % FOR RH 3/31/2020
        SensorFudge(9) = 0.5; % FOR RH 3/31/2020
        SensorFudge(7:8) = 0.2; % FOR RH 3/31/2020
        % FreqMin and FreqMax and PWDur will be fixed for now (e.g. not GUI adjustable).
        FMin = 1; % Hz
        FMax = 200; % Hz
        PW = 0.1; % ms
        FreqMin = ones(size(Elecs))*FMin;
        FreqMax = ones(size(Elecs))*FMax;
        PWDur = ones(size(Elecs))*PW;
        
        %% These field will be updated via the GUI
        % Amin and Amax WILL BE UPDATED for each sensor (all electrodes tied to
        % that sensor will have the same Amin and Amax) using the GUI interface
        AMin = 1; % uA
        AMax = 100; % uA
        AmpMin = ones(size(Elecs))*AMin; % They don't all need to be the same...GUI should actually train each sensor (and assoc. electrodes).
        AmpMax = ones(size(Elecs))*AMax; % Also, different for each sensor based on training
        
        % DEKA_ONOFF WILL BE UPDATED for each sensor using the GUI
        % When the GUI deactivates a specific sensor the corresponding index in
        % this array becomes a 1.
        % Sensor Order: index_medial, index_tip, middle, ring, pinky, palm_distal,
        % palm_proximal, hand_side, hand_back, thumb_ulnar (e.g. force applied that
        % pushes thumb toward ulnar side of hand), thumb_radial (e.g. opp. ulnar),
        % thumb_tip, thumb_dorsal (e.g. force applied to thumb nail)
        
        
        DEKA_ONOFF = zeros(length(DEKAMapping),1); %%% which stim (DEKA sensors) are on (0) and which are off (1)
        if(isempty(DEKA_ONOFF_GUI) == 0) %%If this array isn't empty, set it to the proper values.
            DEKA_ONOFF = DEKA_ONOFF_GUI; %Set values to values given by the GUI
        end
        % DEKA_ONOFF([1,8]) =1; %turn off by setting to 1
        
        % StimOn WILL BE UPDATED with GUI. If StimOn = 1 stim is on, if 0 its off
        % This turns global stim on and off (whereas DEKA_ONOFF toggles a specific
        % sensor).
        StimOn = 0;
        
        %% This code will send these parameters to the Nomad so that they can be
        % read in and implemented in real-time.
        % This Code MUST BE IN THIS FORM, otherwise the Nomad won't reconstruct the
        % data correctly
        Dlength = [1;...
            size(Elecs,1);...
            size(DEKAMapping,1);...
            size(AmpMin,1);...
            size(AmpMax,1);...
            size(FreqMin,1);...
            size(FreqMax,1);...
            size(PWDur,1);...
            size(DEKA_ONOFF,1);...
            size(EncodeAlg,1);...
            size(StimOn,1);...
            size(SensorFudge,1);...
            ];
        Data = [...
            reshape(Elecs,[numel(Elecs),1]);...
            reshape(DEKAMapping,[numel(DEKAMapping),1]);...
            reshape(AmpMin,[numel(AmpMin),1]);...
            reshape(AmpMax,[numel(AmpMax),1]);...
            reshape(FreqMin,[numel(FreqMin),1]);...
            reshape(FreqMax,[numel(FreqMax),1]);...
            reshape(PWDur,[numel(PWDur),1]);...
            reshape(DEKA_ONOFF,[numel(DEKA_ONOFF),1]);...
            reshape(EncodeAlg,[numel(EncodeAlg),1]);...
            reshape(StimOn,[numel(StimOn),1]);...
            reshape(SensorFudge,[numel(SensorFudge),1]);...
            ];
        yDec = ms_send_array(CmdStr,Dlength'); %send info about the length of each data field
        yDec = ms_send_array(CmdStr,Data'); %send data
           end
    end
    

    methods (Access = private)

        % Button pushed function: StimRegion2sButton
        function StimRegion2sButtonPushed(app, event)
               
            
            yDec = ms_send_array('stimReg',region,amplitude'); %send data
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StimRegion2sButton = uibutton(app.UIFigure, 'push');
            app.StimRegion2sButton.ButtonPushedFcn = createCallbackFcn(app, @StimRegion2sButtonPushed, true);
            app.StimRegion2sButton.Text = 'Stim Region 2s';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '-';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '+';
            app.SaveandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveandExitButton.Text = 'Save and Exit';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = stimUpdate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end